classdef registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ManualTab                    matlab.ui.container.Tab
        RegisterManuallyButton       matlab.ui.control.Button
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        AutomaticTab                 matlab.ui.container.Tab
        RegisterAutomaticallyButton  matlab.ui.control.Button
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function registerAuto(app)
            %read in images
            image1 = rgb2gray(imread("fixed.jpg"));
            image2 = rgb2gray(imread("moving.jpg"));
            
            % harris corner dectector
            point1 = detectHarrisFeatures(image1);
            point2 = detectHarrisFeatures(image2);
            
            % extracting features from harris dectector
            [features1,valid_points1] = extractFeatures(image1,point1);
            [features2,valid_points2] = extractFeatures(image2,point2);
            
            % match corresponding features
            pairs = matchFeatures (features1, features2);
            
            % match corresponding points of the corresponding features
            matchedPoints1 = valid_points1(pairs(:,1),:);
            matchedPoints2 = valid_points2(pairs(:,2),:);
            
            % use ransac to eliminate outlier points. 
           [~, inlierpoints1, inlierpoints2] = estimateGeometricTransform(matchedPoints1, matchedPoints2, 'projective');
           
            
            %apply the transform
            t = fitgeotrans(inlierpoints1,inlierpoints2,'projective');
            
            % Applying the transformation to the moving image
            Rfixed = imref2d(size(move));
            registered = imwarp(fixed,t,'OutputView',Rfixed);
            imshowpair(registered, fixed, 'blend', 'Parent', app.UIAxes7);
            
        end

        % Button pushed function: RegisterManuallyButton
        function register(app, event)
            fixed = imread("fixed.jpg");
            move = imread("moving.jpg");
            
            % Manual selection of image points.
            [movingPoints, fixedPoints] = cpselect(fixed, move, 'Wait', true);
            
            % Projective Homography
            t = fitgeotrans(movingPoints,fixedPoints,'projective');
            
            % Applying the transformation to the moving image
            Rfixed = imref2d(size(move));
            registered = imwarp(fixed,t,'OutputView',Rfixed);
            imshow(fixed, 'Parent', app.UIAxes4);
            imshow(move, 'Parent', app.UIAxes5);
            imshowpair(registered, fixed, 'blend', 'Parent', app.UIAxes6);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual ';
            app.RegisterManuallyButton = uibutton(app.ManualTab, 'push');
            app.RegisterManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @register, true);
            app.RegisterManuallyButton.Text = 'Register Manually';
            app.UIAxes4 = uiaxes(app.ManualTab);
            title(app.UIAxes4, 'Image 1')
            app.UIAxes4.Visible = 'off';
            app.UIAxes5 = uiaxes(app.ManualTab);
            title(app.UIAxes5, 'Image 2')
            app.UIAxes5.Visible = 'off';
            app.UIAxes6 = uiaxes(app.ManualTab);
            title(app.UIAxes6, 'Registered Image')
            app.UIAxes6.Visible = 'off';
            app.AutomaticTab = uitab(app.TabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.RegisterAutomaticallyButton = uibutton(app.AutomaticTab, 'push');
            app.RegisterAutomaticallyButton.Text = 'Register Automatically ';
            app.UIAxes7 = uiaxes(app.AutomaticTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @registerAuto)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end